classdef Dashboard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DashboardUIFigure               matlab.ui.Figure
        EditField_6                     matlab.ui.control.NumericEditField
        selected_2                      matlab.ui.control.Label
        high_2                          matlab.ui.control.Label
        current_2                       matlab.ui.control.Label
        Label_78                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_1212                      matlab.ui.control.Label
        Label_111111                    matlab.ui.control.Label
        Label_1010                      matlab.ui.control.Label
        Label_99                        matlab.ui.control.Label
        Label_88                        matlab.ui.control.Label
        Label_1111                      matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Kneeabductionmoment90cutsLabel  matlab.ui.control.Label
        xButton                         matlab.ui.control.Button
        EditField                       matlab.ui.control.NumericEditField
        selected                        matlab.ui.control.Label
        high                            matlab.ui.control.Label
        current                         matlab.ui.control.Label
        Label_66                        matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        DropDown_11                     matlab.ui.control.DropDown
        Kneeabductionmoment180cutsLabel  matlab.ui.control.Label
        DropDown_10                     matlab.ui.control.DropDown
        DropDown_9                      matlab.ui.control.DropDown
        DropDown_8                      matlab.ui.control.DropDown
        Label_11                        matlab.ui.control.Label
        Label_55                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        TechniqueVariables90cutsLabel   matlab.ui.control.Label
        TechniqueVariables180cutsLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        LoadFile                        matlab.ui.control.Button
        DropDown_7                      matlab.ui.control.DropDown
        EditField_1U_2                  matlab.ui.control.NumericEditField
        EditField_1O_2                  matlab.ui.control.NumericEditField
        DropDown_6                      matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_1                      matlab.ui.control.DropDown
        Label_1                         matlab.ui.control.Label
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        EditField_1U_5                  matlab.ui.control.NumericEditField
        EditField_1O_5                  matlab.ui.control.NumericEditField
        UIAxes6_90                      matlab.ui.control.UIAxes
        UIAxes1_90                      matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
        UIAxes2_90                      matlab.ui.control.UIAxes
        UIAxes5_90                      matlab.ui.control.UIAxes
        UIAxes4_90                      matlab.ui.control.UIAxes
        UIAxes3_90                      matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes22                        matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = public)
        datacontainer; %stores the Initial path and the choosen conditions and participants from the main app
        handle1;
        handle2;
        handle3;
        handle4;
        handle5;
        handle6;
        path_to_file;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, datastruct)
            clc
            % app.DashboardUIFigure.WindowState = 'maximized';
            root = groot;
            % Get the screen size
            screenSize = root.ScreenSize;
            % Extract width and height
            screenWidth = screenSize(3);
            screenHeight = screenSize(4)-20;
            figureWidth = screenWidth; % Width of your figure
            figureHeight = screenHeight; % Height of your figure
            figureX = (screenWidth - figureWidth) / 2; % Center figure horizontally
            figureY = (screenHeight - figureHeight) / 2; % Center figure vertically
            app.DashboardUIFigure.Position = [figureX, figureY, figureWidth, figureHeight];
            %app.datacontainer = load('C:\Users\adpatrick\Downloads\P008.mat');%datastruct;
            try
                app.datacontainer = load(datastruct);
                path_to_file = datastruct;
            catch
                try
                    [file,location] = uigetfile('*.mat');
                    app.datacontainer =load ([location, '/', file]);
                    path_to_file = [location, '/', file];
                catch
                    msgbox("Select a valid mat file!","Select file","error");
                end
            end

            %% frage nach welcher task das lezte file war
            trialnames = fieldnames(app.datacontainer.OPTIONS.TASK_ID_NUMERIC);
            current_task_ID= app.datacontainer.OPTIONS.TASK_ID_NUMERIC.(trialnames{end});
            current_task_name = (trialnames{end});
            current_task_pos = length(trialnames);

            %% pack all into one matrix
            for tr = 1 : length (trialnames)
                matrix(tr,1) = double(app.datacontainer.OPTIONS.ANALYZEDLEG_BIN.(trialnames{tr, 1})) ;
                matrix(tr,2) = double(app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}));
                matrix(tr,3) =double(app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1}));
                matrix(tr,4) =double(app.datacontainer.OPTIONS.TASK_ID_NUMERIC.(trialnames{tr, 1}));
                if matrix(tr,1)==1 & matrix(tr,2) ==1 %& matrix(tr,3) ==0 % wenn rechtes bein und ohne feedback und valid
                    col{tr,1} = [0, 0,0];
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1% & matrix(tr,3) ==0 % wenn rechtes bein und ohne feedback und valid
                    col{tr,1} = [192,192,192]/255;
                else
                    col{tr,1} = [1,1,1];
                end
            end
            matrix(:,5) = [1:length(trialnames)]'; % is the original trial number count for findex

            [no_trials, ~]=size(matrix);

            % was right was valid and no instruduction and task 1
            idxr_180 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==1));
            idxl_180 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==1));
            % was right was valid and no instruduction and task 2
            idxr_90 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==2));
            idxl_90 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==2));

            %% get the mean KAM for L and R wo feedback
            R_mean_KAM_woF_180= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_180,5)));
            L_mean_KAM_woF_180 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_180,5)));

            R_mean_KAM_woF_90= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_90,5)));
            L_mean_KAM_woF_90 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_90,5)));


            for tr = 1 : length (trialnames)
                if matrix(tr,1)==1 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)<=R_mean_KAM_woF_180*0.9)
                    col{tr,1} = [0,128,0]/255; % dunkel gruen
                elseif matrix(tr,1)==1 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)>=R_mean_KAM_woF_180*1.1)
                    col{tr,1} = [220,20,60]/255;
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)<=L_mean_KAM_woF_180*0.9)
                    col{tr,1} = [0,255,0]/255;
                elseif matrix(tr,1)==0 & matrix(tr,2) ==1 & matrix(tr,3) ==1 & (app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)>=L_mean_KAM_woF_180*1.1)
                    col{tr,1} = [255,99,71]/255;
                else
                    col{tr,1} = col{tr,1};
                end


            end


            hunderteight_count =1;
            ninety_count =1;
            axisnumber_90 = 'UIAxes1_90'; % CHANGE
            axisnumber_180 = 'UIAxes1';
            for tr = 1:no_trials
                if matrix(tr,4) ==1 % is 180
                    bar(app.(axisnumber_180),hunderteight_count,app.datacontainer.PARAMETERS.PEAK_KNEE_X(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_180), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X((matrix(idxl_180,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X((matrix(idxr_180,5)))))
                    ylabel(app.(axisnumber_180), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylim(app.(axisnumber_180), [0, length(find(matrix(:,4)==1))+1])
                    yticks(app.(axisnumber_180),[1: length(find(matrix(:,4)==1))]);
                    hunderteight_count = hunderteight_count+1;
                elseif matrix(tr,4) ==2 % is 90
                    bar(app.(axisnumber_90),hunderteight_count,app.datacontainer.PARAMETERS.PEAK_KNEE_X(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_90), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X((matrix(idxl_90,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X((matrix(idxr_90,5)))))
                    ylabel(app.(axisnumber_90), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylim(app.(axisnumber_90), [0, length(find(matrix(:,4)==2))+1])
                    yticks(app.(axisnumber_90),[1: length(find(matrix(:,4)==2))]);
                    ninety_count = ninety_count+1;
                end
            end


            hunderteight_count =1;
            ninety_count =1;
            axisnumber_90 = 'UIAxes2_90'; % CHANGE
            axisnumber_180 = 'UIAxes2';
            parametername = ('TRUNK_LEAN_AT_IC');
            for tr = 1:no_trials
                if matrix(tr,4) ==1 % is 180
                    bar(app.(axisnumber_180),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_180), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_180,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_180,5)))))
                    %ylabel(app.(axisnumber_180), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylim(app.(axisnumber_180), [0, length(find(matrix(:,4)==1))+1])
                    yticks(app.(axisnumber_180),[1: length(find(matrix(:,4)==1))]);
                    ylabel(app.(axisnumber_180), ('Lateral trunk lean [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    xlabel(app.(axisnumber_180), ('(-) outward / (+) inward'), 'Interpreter','none');
                    hunderteight_count = hunderteight_count+1;
                elseif matrix(tr,4) ==2 % is 90
                    bar(app.(axisnumber_90),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_90), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_90,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_90,5)))))
                    %ylabel(app.(axisnumber_90), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylabel(app.(axisnumber_180), ('Lateral trunk lean [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    xlabel(app.(axisnumber_180), ('(-) outward / (+) inward'), 'Interpreter','none');
                    ylim(app.(axisnumber_90), [0, length(find(matrix(:,4)==2))+1])
                    yticks(app.(axisnumber_90),[1: length(find(matrix(:,4)==2))]);
                    ninety_count = ninety_count+1;
                end
            end


            hunderteight_count =1;
            ninety_count =1;
            axisnumber_90 = 'UIAxes3_90'; % CHANGE
            axisnumber_180 = 'UIAxes3';
            parametername = ('KNEE_VALGUS_AT_IC');
            for tr = 1:no_trials
                if matrix(tr,4) ==1 % is 180
                    bar(app.(axisnumber_180),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_180), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_180,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_180,5)))))
                    %ylabel(app.(axisnumber_180), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylim(app.(axisnumber_180), [0, length(find(matrix(:,4)==1))+1])
                    yticks(app.(axisnumber_180),[1: length(find(matrix(:,4)==1))]);
                    ylabel(app.(axisnumber_180), ('Frontal plane knee angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    xlabel(app.(axisnumber_180), ('(-) valgus / (+) varus'), 'Interpreter','none');
                    hunderteight_count = hunderteight_count+1;
                elseif matrix(tr,4) ==2 % is 90
                    bar(app.(axisnumber_90),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_90), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_90,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_90,5)))))
                    %ylabel(app.(axisnumber_90), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylabel(app.(axisnumber_90), ('Frontal plane knee angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    xlabel(app.(axisnumber_90), ('(-) valgus / (+) varus'), 'Interpreter','none');
                    ylim(app.(axisnumber_90), [0, length(find(matrix(:,4)==2))+1])
                    yticks(app.(axisnumber_90),[1: length(find(matrix(:,4)==2))]);
                    ninety_count = ninety_count+1;
                end
            end


            hunderteight_count =1;
            ninety_count =1;
            axisnumber_90 = 'UIAxes4_90'; % CHANGE
            axisnumber_180 = 'UIAxes4';
            parametername = ('FOOT_STRIKE_PATTERN');
            for tr = 1:no_trials
                if matrix(tr,4) ==1 % is 180
                    bar(app.(axisnumber_180),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_180), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_180,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_180),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_180,5)))))
                    %ylabel(app.(axisnumber_180), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
                    ylim(app.(axisnumber_180), [0, length(find(matrix(:,4)==1))+1])
                    yticks(app.(axisnumber_180),[1: length(find(matrix(:,4)==1))]);

                    xlabel(app.(axisnumber_180), '(-) rearfoot / (+) forefoot', 'Interpreter','none');
                    ylabel(app.(axisnumber_180), ('Foot strike angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');

                    hunderteight_count = hunderteight_count+1;
                elseif matrix(tr,4) ==2 % is 90
                    bar(app.(axisnumber_90),hunderteight_count,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col{tr}, 'EdgeColor', 'none')
                    hold(app.(axisnumber_90), 'on'); % Hold on to plot additional data on the same axes
                    xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxl_90,5)))), 'Color',col{tr, 1})
                    %xline(app.(axisnumber_90),mean(app.datacontainer.PARAMETERS.(parametername)((matrix(idxr_90,5)))))
                    %ylabel(app.(axisnumber_90), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');

                    xlabel(app.(axisnumber_90), '(-) rearfoot / (+) forefoot', 'Interpreter','none');
                    ylabel(app.(axisnumber_90), ('Foot strike angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');

                    ylim(app.(axisnumber_90), [0, length(find(matrix(:,4)==2))+1])
                    yticks(app.(axisnumber_90),[1: length(find(matrix(:,4)==2))]);
                    ninety_count = ninety_count+1;
                end
            end

            % %
            % % % was right was valid and no instruduction and task 1
            % % idxr_180 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==1));
            % % idxl_180 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==1));
            % % % was right was valid and no instruduction and task 2
            % % idxr_90 = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0)& (matrix(:,4) ==2));
            % % idxl_90 = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0)&(matrix(:,4) ==2));
            % %
            % % %% get the mean KAM for L and R wo feedback
            % % R_mean_KAM_woF_180= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_180,5)));
            % % L_mean_KAM_woF_180 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_180,5)));
            % %
            % % R_mean_KAM_woF_90= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxr_90,5)));
            % % L_mean_KAM_woF_90 = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(matrix(idxl_90,5)));
            %
            %
            % %% first plot the mean KAM in each figure
            %
            % %% make all in a loop todo all figures will be made in a loop and assignied depending on matrix entry in the correct field
            %
            % if current_task_ID ==1
            %     %% plotte mean mit feedback
            %     [~, pos]=max(app.datacontainer.PARAMETERS.PEAK_KNEE_X);
            %     %% add textlabels
            %     app.Label_1.Text = ['Cutting Angle: ', num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(end),1))];
            %     app.Label_11.Text =num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(pos),1));
            %     %%
            %     app.Label_2.Text = ['Approach Speed: ', num2str(round(app.datacontainer.PARAMETERS.VELOCITY(end),1))];
            %     app.Label_22.Text =num2str(round(app.datacontainer.PARAMETERS.VELOCITY(pos),1));
            %     %%
            %     app.Label_3.Text = ['ResGRF: ', num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(end),2))];
            %     app.Label_33.Text =num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(pos),2));
            %     %%
            %     app.Label_4.Text = ['Moment arm: ', num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(end),2))];
            %     app.Label_44.Text =num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(pos),2));
            %     %%
            %     app.Label_5.Text = ['Contact time: ', num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(end),3))];
            %     app.Label_55.Text =num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(pos),3));
            %     %%
            %     app.Label_6.Text = ['pKAM: ', num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(end),2))];
            %     app.Label_66.Text =num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(pos),2));
            %
            %     app.current.Text = app.datacontainer.NORMAL.HEADER  {end};
            %     app.high.Text =app.datacontainer.NORMAL.HEADER  {pos};
            % else
            %     %% plotte mean mit feedback
            %     [~, pos]=max(app.datacontainer.PARAMETERS.PEAK_KNEE_X);
            %     %% add textlabels
            %     app.Label_1.Text = ['Cutting Angle: ', num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(end),1))];
            %     app.Label_11.Text =num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(pos),1));
            %     %%
            %     app.Label_2.Text = ['Approach Speed: ', num2str(round(app.datacontainer.PARAMETERS.VELOCITY(end),1))];
            %     app.Label_22.Text =num2str(round(app.datacontainer.PARAMETERS.VELOCITY(pos),1));
            %     %%
            %     app.Label_3.Text = ['ResGRF: ', num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(end),2))];
            %     app.Label_33.Text =num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(pos),2));
            %     %%
            %     app.Label_4.Text = ['Moment arm: ', num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(end),2))];
            %     app.Label_44.Text =num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(pos),2));
            %     %%
            %     app.Label_5.Text = ['Contact time: ', num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(end),3))];
            %     app.Label_55.Text =num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(pos),3));
            %     %%
            %     app.Label_6.Text = ['pKAM: ', num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(end),2))];
            %     app.Label_66.Text =num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(pos),2));
            %
            %     app.current.Text = app.datacontainer.NORMAL.HEADER  {end};
            %     app.high.Text =app.datacontainer.NORMAL.HEADER  {pos};
            %
            % end
            % %% update die labels
            % %% finde mit dieser tasks den cut mit hoehsten KAM und update die labels (rot)
            % %% plote alle KAM kurven der aktuellen task in UIAxes und highlighte aktuellen trial
            % %% baue matrix mit was valid, bein, feedback given und tasks
            % %% berechne mean KAM von baseline links und rechts fuer tasks
            % %% plotte und mache fuer alle anderen parameter plotte mean
            % %% plotte mean mit feedback
            % % [~, pos]=max(app.datacontainer.PARAMETERS.PEAK_KNEE_X);
            % % %% add textlabels
            % % app.Label_1.Text = ['Cutting Angle: ', num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(end),1))];
            % % app.Label_11.Text =num2str(round(app.datacontainer.PARAMETERS.CuttingAngle(pos),1));
            % % %%
            % % app.Label_2.Text = ['Approach Speed: ', num2str(round(app.datacontainer.PARAMETERS.VELOCITY(end),1))];
            % % app.Label_22.Text =num2str(round(app.datacontainer.PARAMETERS.VELOCITY(pos),1));
            % % %%
            % % app.Label_3.Text = ['ResGRF: ', num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(end),2))];
            % % app.Label_33.Text =num2str(round(app.datacontainer.PARAMETERS.RESGRFatPeakKAM(pos),2));
            % % %%
            % % app.Label_4.Text = ['Moment arm: ', num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(end),2))];
            % % app.Label_44.Text =num2str(round(app.datacontainer.PARAMETERS.MOMENTARMatPeakKAM(pos),2));
            % % %%
            % % app.Label_5.Text = ['Contact time: ', num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(end),3))];
            % % app.Label_55.Text =num2str(round(app.datacontainer.PARAMETERS.CONTACT_TIME(pos),3));
            % % %%
            % % app.Label_6.Text = ['pKAM: ', num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(end),2))];
            % % app.Label_66.Text =num2str(round(app.datacontainer.PARAMETERS.PEAK_KNEE_X(pos),2));
            % %
            % % app.current.Text = app.datacontainer.NORMAL.HEADER  {end};
            % % app.high.Text =app.datacontainer.NORMAL.HEADER  {pos};
            %
            % app.DropDown_1.Items = fieldnames(app.datacontainer.PARAMETERS);
            % app.DropDown_2.Items = fieldnames(app.datacontainer.PARAMETERS);
            % app.DropDown_3.Items = fieldnames(app.datacontainer.PARAMETERS);
            % app.DropDown_4.Items = fieldnames(app.datacontainer.PARAMETERS);
            % app.DropDown_6.Items = fieldnames(app.datacontainer.PARAMETERS);
            % app.DropDown_5.Items = fieldnames(app.datacontainer.PARAMETERS);
            %
            % %% things to show when launching
            % plot(app.UIAxes6, app.datacontainer.NORMAL.KAM(:,[find(matrix(:,4) ==current_task_ID)]), 'LineWidth',2)
            %
            % hold(app.UIAxes6, 'on');
            % % Find indices where the line is below zero
            % below_zero_indices = app.datacontainer.NORMAL.KAM(:,end) < 0;
            % % Find indices where the line is above zero or equal to zero
            % above_zero_indices = app.datacontainer.NORMAL.KAM(:,end) > 0;
            % % Plot segments below zero in green
            % plot(app.UIAxes6, find(below_zero_indices), app.datacontainer.NORMAL.KAM(below_zero_indices,end), 'g', 'LineWidth', 2);
            % % Plot segments above or equal to zero in red
            % hh2 = plot(app.UIAxes6, find(above_zero_indices), app.datacontainer.NORMAL.KAM(above_zero_indices,end), 'r', 'LineWidth', 2);
            % yline(app.UIAxes6,0, 'LineWidth',0.5, 'LineStyle','--')
            % ylabel(app.UIAxes6, 'KAM [Nm/kg]', 'Interpreter','none');
            % xlim(app.UIAxes6,[1,201]);
            % xlabel(app.UIAxes6,'[ms of stance]');
            % set(app.UIAxes6, 'LineWidth', 1.5)
            % set(app.UIAxes6, 'Xtick', [1:50:201]);
            % set(app.UIAxes6, 'XTickLabel', {'0', '25', '50', '75', '100'});
            % set(app.UIAxes6, 'LineWidth', 1.5);
            % set(app.UIAxes6,'color','w');
            % set(app.UIAxes6, 'FontSize', 10);
            % set(app.UIAxes6, 'Fontname', 'Arial');
            % leg = legend (hh2, 'Knee abduction moment');
            % set(leg, 'box', 'off'); % Turn off legend box
            % set(leg, 'Location', 'best'); % Set location to best
            % hold(app.UIAxes6 ,'off');
            %
            % app.DropDown_7.Items = trialnames;
            % app.DropDown_7.Value = (trialnames{end, 1});
            %app.datacontainer.CONTACT_downsampled.(trialnames{end, 1}) = getContact_FP_app(app.datacontainer.FP.(trialnames{end, 1}).GRFfilt_downsampled.(GRFsite)(3,:), app.datacontainer.OPTIONS.ForceTreshold);
            %make_stick_figure_new(app.datacontainer.OPTIONS, app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{end, 1}) ,app.datacontainer.MARKERS, app.datacontainer.OPTIONS.ForcePlateNumber, (trialnames{end, 1}), app.datacontainer.CONTACT_KINEMATIC, app, app.datacontainer.NORMAL, app.datacontainer.BK_POS_TABLE, app.datacontainer.GRF_TABLE, app.datacontainer.MOMENT_TABLE)


            %% subplots for discrete


            % for tr = 1 : length (trialnames)
            %     matrix(tr,1) = app.datacontainer.OPTIONS.ANALYZEDLEG_BIN.(trialnames{tr, 1}) ;
            %     matrix(tr,2) = app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1});
            %     matrix(tr,3) =app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1});
            % end
            %
            %
            % % was right was valid and no instruduction
            % idxr = find(matrix(:,1) ==1 & (matrix(:,2) ==1) & (matrix(:,3) ==0));
            % idxl = find(matrix(:,1) ==0 & (matrix(:,2) ==1) & (matrix(:,3) ==0));
            %
            % %% get the mean KAM for L and R wo feedback
            % R_mean_KAM_woF= mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxr));
            % L_mean_KAM_woF = mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxl));

            %%
            % for
            %                 bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.PEAK_KNEE_X(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxr)))
            %     ylabel(app.(axisnumber), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            % %trialnames = fieldnames(app.datacontainer.CONTACT);
            % axisnumber = 'UIAxes1';
            % handle = 'handle1';
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.PEAK_KNEE_X(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X(idxr)))
            %     ylabel(app.(axisnumber), ('pKAM [Nm/kg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X) >0 && min(app.datacontainer.PARAMETERS.PEAK_KNEE_X)>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.PEAK_KNEE_X)])
            %     elseif mean(app.datacontainer.PARAMETERS.PEAK_KNEE_X) <0  && max(app.datacontainer.PARAMETERS.PEAK_KNEE_X)<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.PEAK_KNEE_X),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.PEAK_KNEE_X),max(app.datacontainer.PARAMETERS.PEAK_KNEE_X)])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            % %% 180
            % axisnumber = 'UIAxes2';
            % handle = 'handle2';
            % parametername = ('TRUNK_LEAN_AT_IC');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Lateral trunk lean [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     xlabel(app.(axisnumber), ('(-) outward / (+) inward'), 'Interpreter','none');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            % axisnumber = 'UIAxes3';
            % handle = 'handle3';
            % parametername = ('KNEE_VALGUS_AT_IC');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Frontal plane knee angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), ('(-) valgus / (+) varus'), 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            %
            % axisnumber = 'UIAxes4';
            % handle = 'handle4';
            % parametername = ('FOOT_STRIKE_PATTERN');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Foot strike angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), '(-) rearfoot / (+) forefoot', 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            %
            %
            % axisnumber = 'UIAxes5';
            % handle = 'handle5';
            % parametername = ('FOOT_PROGRESSION_ANGLE');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Foot progression angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), ('(-) external / (+) internal'), 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % % app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            % %% 90
            % axisnumber = 'UIAxes2_90';
            % handle = 'handle2_90';
            % parametername = ('TRUNK_LEAN_AT_IC');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Lateral trunk lean [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     xlabel(app.(axisnumber), ('(-) outward / (+) inward'), 'Interpreter','none');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            % axisnumber = 'UIAxes3_90';
            % handle = 'handle3_90';
            % parametername = ('KNEE_VALGUS_AT_IC');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Frontal plane knee angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), ('(-) valgus / (+) varus'), 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            %
            % axisnumber = 'UIAxes4_90';
            % handle = 'handle4_90';
            % parametername = ('FOOT_STRIKE_PATTERN');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Foot strike angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), '(-) rearfoot / (+) forefoot', 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];
            % %app.(handle) = patch(app.(axisnumber),x,y,'red', 'FaceAlpha',.2, "Linestyle", "none");
            %
            %
            %
            % axisnumber = 'UIAxes5_90';
            % handle = 'handle5_90';
            % parametername = ('FOOT_PROGRESSION_ANGLE');
            % hold(app.(axisnumber), 'off');
            % for tr = 1 : length (trialnames)
            %     %app.datacontainer.ANALYZEDLEG app.datacontainer.OPTIONS.WAS_VALID app.datacontainer.OPTIONS.Instruction
            %     if strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [0, 0,0];
            %     elseif strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1 && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==0
            %         col = [192,192,192]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [0,128,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > R_mean_KAM_woF-(10*R_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'R') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [139,0,0]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  < L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [44,238,144]/255;
            %     elseif   app.datacontainer.PARAMETERS.PEAK_KNEE_X(tr)  > L_mean_KAM_woF-(10*L_mean_KAM_woF)/100 && strcmp (app.datacontainer.OPTIONS.ANALYZEDLEG.(trialnames{tr, 1})   , 'L') && app.datacontainer.OPTIONS.Instruction.(trialnames{tr, 1})  ==1 && app.datacontainer.OPTIONS.WAS_VALID.(trialnames{tr, 1}) ==1
            %         col = [233,150,122]/255;
            %     else
            %         col = [255,255,0]/255;
            %     end
            %     bar(app.(axisnumber),tr,app.datacontainer.PARAMETERS.(parametername)(:,tr), 'Horizontal', 'on', 'FaceColor', 'flat', 'CData', col, 'EdgeColor', 'none')
            %     hold(app.(axisnumber), 'on'); % Hold on to plot additional data on the same axes
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxl)), 'Color',[192,192,192]/255)
            %     xline(app.(axisnumber),mean(app.datacontainer.PARAMETERS.(parametername)(idxr)))
            %     ylabel(app.(axisnumber), ('Foot progression angle [deg]'), 'Interpreter','none', 'FontWeight', 'bold');
            %     ylim(app.(axisnumber), [0, length(trialnames)+1])
            %     xlabel(app.(axisnumber), ('(-) external / (+) internal'), 'Interpreter','none');
            %     yticks(app.(axisnumber),[1: length(trialnames)]);
            %
            %     if mean(app.datacontainer.PARAMETERS.(parametername)) >0 && min(app.datacontainer.PARAMETERS.(parametername))>0
            %         xlim(app.(axisnumber), [0, max(app.datacontainer.PARAMETERS.(parametername))])
            %     elseif mean(app.datacontainer.PARAMETERS.(parametername)) <0  && max(app.datacontainer.PARAMETERS.(parametername))<0
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),0])
            %     else
            %         xlim(app.(axisnumber), [min(app.datacontainer.PARAMETERS.(parametername)),max(app.datacontainer.PARAMETERS.(parametername))])
            %     end
            % end
            % ylimits = app.(axisnumber).YLim;
            % x = [1 200 200 1];
            % y = [min(ylimits) min(ylimits) max(ylimits) max(ylimits)];


        end

        % Value changed function: DropDown_1
        function DropDown_1ValueChanged(app, event)
            update_subplots(app,'UIAxes1', 'DropDown_1')
        end

        % Callback function
        function EditField_1OValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O', 'EditField_1U', 'UIAxes1', 'handle1');
        end

        % Callback function
        function EditField_1UValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O', 'EditField_1U', 'UIAxes1', 'handle1');
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            update_subplots(app,'UIAxes2', 'DropDown_2')

        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            update_subplots(app,'UIAxes3', 'DropDown_3')

        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
            update_subplots(app,'UIAxes4', 'DropDown_4')

        end

        % Value changed function: DropDown_5
        function DropDown_5ValueChanged(app, event)
            update_subplots(app,'UIAxes5', 'DropDown_5')

        end

        % Value changed function: DropDown_6
        function DropDown_6ValueChanged(app, event)
            update_subplots(app,'UIAxes6', 'DropDown_6')

        end

        % Value changed function: EditField_1O_2, EditField_1U_2
        function EditField_1O_2ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_2', 'EditField_1U_2', 'UIAxes2', 'handle2');

        end

        % Callback function
        function EditField_1O_3ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_3', 'EditField_1U_3', 'UIAxes3', 'handle3');

        end

        % Callback function
        function EditField_1O_4ValueChanged(app, event)

            %             app= add_patch_interactive(app,'EditField_1O_4', 'EditField_1U_4', 'UIAxes4', 'handle4');

        end

        % Value changed function: EditField_1O_5, EditField_1U_5
        function EditField_1O_5ValueChanged(app, event)
            %             app= add_patch_interactive(app,'EditField_1O_5', 'EditField_1U_5', 'UIAxes5', 'handle5');
        end

        % Value changed function: DropDown_7
        function DropDown_7ValueChanged(app, event)
            trialnames = fieldnames(app.datacontainer.GRF_TABLE);


            % app.DropDown_7.Items = trialnames;
            % app.DropDown_7.Value = (trialnames{end, 1});

            %make_stick_figure_new(app.datacontainer.OPTIONS, app.datacontainer.ANALYZEDLEG.(app.DropDown_7.Value),app.datacontainer.MARKERS, app.datacontainer.FP, app.datacontainer.WINKEL, app.datacontainer.KINETICS, (app.DropDown_7.Value), app.datacontainer.kneefrontalplanemoment, app.datacontainer.CONTACT,  app.datacontainer.CONTACT_downsampled, app)
            %make_stick_figure_new(app.datacontainer.OPTIONS, app.datacontainer.OPTIONS.ANALYZEDLEG.(app.DropDown_7.Value),app.datacontainer.MARKERS, app.datacontainer.OPTIONS.ForcePlateNumber, (app.DropDown_7.Value), app.datacontainer.CONTACT_KINEMATIC, app, app.datacontainer.NORMAL, app.datacontainer.BK_POS_TABLE, app.datacontainer.GRF_TABLE, app.datacontainer.MOMENT_TABLE)

            index_current_struct = find(strcmp(app.datacontainer.NORMAL.HEADER,(app.DropDown_7.Value) ) ==1);
            plot(app.UIAxes6, app.datacontainer.NORMAL.KAM(:,index_current_struct))
            ylabel(app.UIAxes6, 'KAM [Nm/kg]', 'Interpreter','none', 'FontWeight', 'bold');


        end

        % Button pushed function: LoadFile
        function LoadFileButtonPushed(app, event)


            [file,location] = uigetfile('*.mat','Select File');
            app.datacontainer =load ([location, '/', file]);
            path_to_file = [location, '/', file];

            startupFcn(app, app.datacontainer )

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)

            try
                app.selected.Text = app.datacontainer.NORMAL.HEADER{app.EditField.Value};
            catch
            end
        end

        % Button pushed function: xButton
        function xButtonPushed(app, event)
            %% last trialname
            all_trialnames = fieldnames (app.datacontainer.OPTIONS.WAS_VALID);
            trialname_delete = (all_trialnames{end, 1});
            % Define a function to recursively loop through the structs
            % Call the function to start the recursive looping
            [app.datacontainer]=recursiveStructLoop(app.datacontainer, trialname_delete);
            [app.datacontainer.OPTIONS]=recursiveStructLoop(app.datacontainer.OPTIONS, trialname_delete);
            n_names = fieldnames(app.datacontainer.NORMAL);
            for n = 1:length(n_names)
                app.datacontainer.NORMAL.(n_names{n, 1})(:,end) = [];
            end

            n_names = fieldnames(app.datacontainer.PARAMETERS);
            for n = 1:length(n_names)
                app.datacontainer.PARAMETERS.(n_names{n, 1})(:,end) = [];
            end
            %% save the mat file a reload the dasboard
            DATA = app.datacontainer;
            %app.datacontainer.DATA.OPTIONS.MAT_FILE_LOCATION= 'C:\OLE_APP_stable/P00X.mat'
            save([app.datacontainer.OPTIONS.MAT_FILE_LOCATION], '-struct', 'DATA');
            % delete(findall(0, 'Type', 'figure', 'Name', 'Dashboard'));
            Dashboard([app.datacontainer.OPTIONS.MAT_FILE_LOCATION]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DashboardUIFigure = uifigure('Visible', 'off');
            app.DashboardUIFigure.Name = 'Dashboard';
            app.UIAxes3 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes3, ' ')
            ylabel(app.UIAxes3, ' ')
            zlabel(app.UIAxes3, ' ')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes4, ' ')
            ylabel(app.UIAxes4, ' ')
            zlabel(app.UIAxes4, ' ')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes5, ' ')
            ylabel(app.UIAxes5, ' ')
            zlabel(app.UIAxes5, ' ')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes22 = uiaxes(app.DashboardUIFigure);
            app.UIAxes22.XTick = [];
            app.UIAxes22.YTick = [];
            app.UIAxes22.ZTick = [];
            app.UIAxes6 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes6, ' ')
            ylabel(app.UIAxes6, ' ')
            zlabel(app.UIAxes6, ' ')
            app.UIAxes6.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes2, ' ')
            ylabel(app.UIAxes2, ' ')
            zlabel(app.UIAxes2, ' ')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes3_90, ' ')
            ylabel(app.UIAxes3_90, ' ')
            zlabel(app.UIAxes3_90, ' ')
            app.UIAxes3_90.XTick = [];
            app.UIAxes3_90.YTick = [];
            app.UIAxes4_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes4_90, ' ')
            ylabel(app.UIAxes4_90, ' ')
            zlabel(app.UIAxes4_90, ' ')
            app.UIAxes4_90.XTick = [];
            app.UIAxes4_90.YTick = [];
            app.UIAxes5_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes5_90, ' ')
            ylabel(app.UIAxes5_90, ' ')
            zlabel(app.UIAxes5_90, ' ')
            app.UIAxes5_90.XTick = [];
            app.UIAxes5_90.YTick = [];
            app.UIAxes2_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes2_90, ' ')
            ylabel(app.UIAxes2_90, ' ')
            zlabel(app.UIAxes2_90, ' ')
            app.UIAxes2_90.XTick = [];
            app.UIAxes2_90.YTick = [];
            app.UIAxes1 = uiaxes(app.DashboardUIFigure);
            ylabel(app.UIAxes1, ' ')
            zlabel(app.UIAxes1, ' ')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = 1;
            app.UIAxes1_90 = uiaxes(app.DashboardUIFigure);
            ylabel(app.UIAxes1_90, ' ')
            zlabel(app.UIAxes1_90, ' ')
            app.UIAxes1_90.XTick = [];
            app.UIAxes1_90.YTick = 1;
            app.UIAxes6_90 = uiaxes(app.DashboardUIFigure);
            xlabel(app.UIAxes6_90, ' ')
            ylabel(app.UIAxes6_90, ' ')
            zlabel(app.UIAxes6_90, ' ')
            app.UIAxes6_90.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes6_90.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.EditField_1O_5 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1O_5.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_5ValueChanged, true);
            app.EditField_1O_5.Visible = 'off';
            app.EditField_1O_5.Value = 5;
            app.EditField_1U_5 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1U_5.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_5ValueChanged, true);
            app.EditField_1U_5.Visible = 'off';
            app.EditField_1U_5.Value = 1.3;
            app.DropDown_3 = uidropdown(app.DashboardUIFigure);
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Visible = 'off';
            app.DropDown_4 = uidropdown(app.DashboardUIFigure);
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Visible = 'off';
            app.DropDown_5 = uidropdown(app.DashboardUIFigure);
            app.DropDown_5.ValueChangedFcn = createCallbackFcn(app, @DropDown_5ValueChanged, true);
            app.Label_1 = uilabel(app.DashboardUIFigure);
            app.Label_1.Text = ' ';
            app.DropDown_1 = uidropdown(app.DashboardUIFigure);
            app.DropDown_1.ValueChangedFcn = createCallbackFcn(app, @DropDown_1ValueChanged, true);
            app.DropDown_1.Visible = 'off';
            app.DropDown_2 = uidropdown(app.DashboardUIFigure);
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Visible = 'off';
            app.DropDown_6 = uidropdown(app.DashboardUIFigure);
            app.DropDown_6.ValueChangedFcn = createCallbackFcn(app, @DropDown_6ValueChanged, true);
            app.DropDown_6.Visible = 'off';
            app.EditField_1O_2 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1O_2.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_2ValueChanged, true);
            app.EditField_1O_2.Visible = 'off';
            app.EditField_1O_2.Value = 5;
            app.EditField_1U_2 = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField_1U_2.ValueChangedFcn = createCallbackFcn(app, @EditField_1O_2ValueChanged, true);
            app.EditField_1U_2.Visible = 'off';
            app.EditField_1U_2.Value = 1.3;
            app.DropDown_7 = uidropdown(app.DashboardUIFigure);
            app.DropDown_7.ValueChangedFcn = createCallbackFcn(app, @DropDown_7ValueChanged, true);
            app.DropDown_7.Visible = 'off';
            app.LoadFile = uibutton(app.DashboardUIFigure, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFile.Text = 'Browse';
            app.Label_2 = uilabel(app.DashboardUIFigure);
            app.Label_2.Text = ' ';
            app.Label_3 = uilabel(app.DashboardUIFigure);
            app.Label_3.Text = ' ';
            app.Label_4 = uilabel(app.DashboardUIFigure);
            app.Label_4.Text = ' ';
            app.Label_5 = uilabel(app.DashboardUIFigure);
            app.Label_5.Text = ' ';
            app.TechniqueVariables180cutsLabel = uilabel(app.DashboardUIFigure);
            app.TechniqueVariables180cutsLabel.Text = 'Technique Variables 180° cuts';
            app.TechniqueVariables90cutsLabel = uilabel(app.DashboardUIFigure);
            app.TechniqueVariables90cutsLabel.Text = 'Technique Variables 90° cuts';
            app.Label_22 = uilabel(app.DashboardUIFigure);
            app.Label_22.Text = ' ';
            app.Label_33 = uilabel(app.DashboardUIFigure);
            app.Label_33.Text = ' ';
            app.Label_44 = uilabel(app.DashboardUIFigure);
            app.Label_44.Text = ' ';
            app.Label_55 = uilabel(app.DashboardUIFigure);
            app.Label_55.Text = ' ';
            app.Label_11 = uilabel(app.DashboardUIFigure);
            app.Label_11.Text = ' ';
            app.DropDown_8 = uidropdown(app.DashboardUIFigure);
            app.DropDown_8.Visible = 'off';
            app.DropDown_9 = uidropdown(app.DashboardUIFigure);
            app.DropDown_9.Visible = 'off';
            app.DropDown_10 = uidropdown(app.DashboardUIFigure);
            app.Kneeabductionmoment180cutsLabel = uilabel(app.DashboardUIFigure);
            app.Kneeabductionmoment180cutsLabel.Text = 'Knee abduction moment 180° cuts';
            app.DropDown_11 = uidropdown(app.DashboardUIFigure);
            app.DropDown_11.Visible = 'off';
            app.Label_6 = uilabel(app.DashboardUIFigure);
            app.Label_6.Text = ' ';
            app.Label_66 = uilabel(app.DashboardUIFigure);
            app.Label_66.Text = ' ';
            app.current = uilabel(app.DashboardUIFigure);
            app.current.Text = ' ';
            app.high = uilabel(app.DashboardUIFigure);
            app.high.Text = ' ';
            app.selected = uilabel(app.DashboardUIFigure);
            app.selected.Text = ' ';
            app.EditField = uieditfield(app.DashboardUIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.xButton = uibutton(app.DashboardUIFigure, 'push');
            app.xButton.ButtonPushedFcn = createCallbackFcn(app, @xButtonPushed, true);
            app.xButton.Text = 'x';
            app.Kneeabductionmoment90cutsLabel = uilabel(app.DashboardUIFigure);
            app.Kneeabductionmoment90cutsLabel.Text = 'Knee abduction moment 90° cuts';
            app.Label_7 = uilabel(app.DashboardUIFigure);
            app.Label_7.Text = ' ';
            app.Label_8 = uilabel(app.DashboardUIFigure);
            app.Label_8.Text = ' ';
            app.Label_9 = uilabel(app.DashboardUIFigure);
            app.Label_9.Text = ' ';
            app.Label_10 = uilabel(app.DashboardUIFigure);
            app.Label_10.Text = ' ';
            app.Label_1111 = uilabel(app.DashboardUIFigure);
            app.Label_1111.Text = ' ';
            app.Label_88 = uilabel(app.DashboardUIFigure);
            app.Label_88.Text = ' ';
            app.Label_99 = uilabel(app.DashboardUIFigure);
            app.Label_99.Text = ' ';
            app.Label_1010 = uilabel(app.DashboardUIFigure);
            app.Label_1010.Text = ' ';
            app.Label_111111 = uilabel(app.DashboardUIFigure);
            app.Label_111111.Text = ' ';
            app.Label_1212 = uilabel(app.DashboardUIFigure);
            app.Label_1212.Text = ' ';
            app.Label_12 = uilabel(app.DashboardUIFigure);
            app.Label_12.Text = ' ';
            app.Label_78 = uilabel(app.DashboardUIFigure);
            app.Label_78.Text = ' ';
            app.current_2 = uilabel(app.DashboardUIFigure);
            app.current_2.Text = ' ';
            app.high_2 = uilabel(app.DashboardUIFigure);
            app.high_2.Text = ' ';
            app.selected_2 = uilabel(app.DashboardUIFigure);
            app.selected_2.Text = ' ';
            app.EditField_6 = uieditfield(app.DashboardUIFigure, 'numeric');
            % Show the figure after all components are created
            app.DashboardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dashboard(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DashboardUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DashboardUIFigure)
        end
    end
end