classdef OLE_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OLEOpenSimLocomotionEnhancerv05UIFigure  matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        LoadmatMenu                     matlab.ui.container.Menu
        SetupMenu                       matlab.ui.container.Menu
        CalgaryMenu                     matlab.ui.container.Menu
        OsloMenu                        matlab.ui.container.Menu
        ToolsMenu                       matlab.ui.container.Menu
        CloseallfiguresMenu             matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        OpenSimMenu                     matlab.ui.container.Menu
        DashboardMenu                   matlab.ui.container.Menu
        Menu_2                          matlab.ui.container.Menu
        SpeedCheckerDropDown            matlab.ui.control.DropDown
        SpeedCheckerDropDownLabel       matlab.ui.control.Label
        TaskselectionButtonGroup        matlab.ui.container.ButtonGroup
        Button3                         matlab.ui.control.RadioButton
        Button_2                        matlab.ui.control.RadioButton
        Button                          matlab.ui.control.RadioButton
        RefidentifierEditField          matlab.ui.control.EditField
        RefidentifierLabel              matlab.ui.control.Label
        SetupDropDown                   matlab.ui.control.DropDown
        SetupDropDownLabel              matlab.ui.control.Label
        SportDropDown                   matlab.ui.control.DropDown
        SportDropDownLabel              matlab.ui.control.Label
        TopfolderButton                 matlab.ui.control.Button
        FoFCheckBox                     matlab.ui.control.CheckBox
        Label_4                         matlab.ui.control.Label
        ToleranceEditField              matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel         matlab.ui.control.Label
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        FeedbackTextInput               matlab.ui.control.EditField
        FeedbackTextYes                 matlab.ui.control.RadioButton
        CoMVelocityButton               matlab.ui.control.RadioButton
        LateralPressButton              matlab.ui.control.RadioButton
        ToeLandingButton                matlab.ui.control.RadioButton
        DuringMeasurmentsLabel          matlab.ui.control.Label
        BeforeMeasurmentStartsLabel     matlab.ui.control.Label
        ActiveGameminEditField          matlab.ui.control.NumericEditField
        ActiveGameminEditFieldLabel     matlab.ui.control.Label
        HandballminEditField            matlab.ui.control.NumericEditField
        HandballminEditFieldLabel       matlab.ui.control.Label
        StrengthminEditField            matlab.ui.control.NumericEditField
        StrengthminEditFieldLabel       matlab.ui.control.Label
        ThrowingarmKickingLegEditField  matlab.ui.control.EditField
        ThrowingarmLabel                matlab.ui.control.Label
        InstructionsgivenCheckBox       matlab.ui.control.CheckBox
        DominantLegEditField            matlab.ui.control.EditField
        DominantLegLabel                matlab.ui.control.Label
        ACLInjuryYearEditField          matlab.ui.control.EditField
        ACLInjuryYearLabel              matlab.ui.control.Label
        InterventionEditField           matlab.ui.control.NumericEditField
        InterventionLabel               matlab.ui.control.Label
        DivisionEditField               matlab.ui.control.EditField
        DivisionLabel                   matlab.ui.control.Label
        PositionEditField               matlab.ui.control.EditField
        PositionLabel                   matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        CutfilesCheckBox                matlab.ui.control.CheckBox
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        AgeyearsEditField               matlab.ui.control.NumericEditField
        AgeyearsLabel                   matlab.ui.control.Label
        IDEditField                     matlab.ui.control.EditField
        SubjectIDLabel                  matlab.ui.control.Label
        File2scanDropDown               matlab.ui.control.DropDown
        FileformatDropDownLabel         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Leg2AnalyzeDropDown             matlab.ui.control.DropDown
        AnalyzedLegLabel                matlab.ui.control.Label
        FPuseDropDown                   matlab.ui.control.DropDown
        ForceplateDropDownLabel         matlab.ui.control.Label
        HeightcmEditField               matlab.ui.control.NumericEditField
        HeightcmLabel                   matlab.ui.control.Label
        MassKgEditField                 matlab.ui.control.NumericEditField
        MassKgLabel                     matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        StopButton                      matlab.ui.control.ToggleButton
        StartButton                     matlab.ui.control.ToggleButton
        FoldertomonitorEditField        matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
    end

    properties (Access = public)
        storageglobal_path='';
        OSIMpath = '';
        data_path = '';
        lab_setting = '';
        setup_options = '';
        setting_file = '';

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                cd 'C:\OLE_APP_stable'

                [dfolders] = get_subfolders([cd, '\', 'OpenSim Master_Folder']);
                for i = 1 : length (dfolders)
                    app.SetupDropDown.Items{end+1} = dfolders(i).name; % Append entry
                end
                app.setting_file = readcell([cd, '\', 'Settings.xlsx']);
                for r = 1: 3
                    app.ButtonGroup_2.Buttons(r).Text = app.setting_file{r, 2};
                end
                alf =2;
            catch
                selpath = uigetdir(cd, 'Select the folder to the app');
                cd (selpath)
            end
        end

        % Button pushed function: TopfolderButton
        function TopfolderButtonPushed(app, event)
            selpath = uigetdir;
            %selpath = 'C:\Users\patrickmai\Desktop\TestFolder'
            try
                app.FoldertomonitorEditField.Value  =  selpath;
                app.storageglobal_path= selpath;
            catch
                uiwait(msgbox("You haven't selected a folder! Use browse to select a valid folder! ","Error","error"));
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            try
                mkdir ([app.storageglobal_path, '/', 'Processed'])
                copyfile([app.FoldertomonitorEditField.Value,'/' ,app.IDEditField.Value,'.mat'], [app.FoldertomonitorEditField.Value, '/', 'Processed']);
                pause(0.01)
                delete ([app.FoldertomonitorEditField.Value,'/' ,app.IDEditField.Value,'.mat']);
            catch
            end
            app_control_OLE(app)
        end

        % Value changed function: MassKgEditField
        function MassKgEditFieldValueChanged(app, event)

        end

        % Value changed function: HeightcmEditField
        function HeightcmEditFieldValueChanged(app, event)

        end

        % Value changed function: FPuseDropDown
        function FPuseDropDownValueChanged(app, event)

        end

        % Value changed function: Leg2AnalyzeDropDown
        function Leg2AnalyzeDropDownValueChanged(app, event)
            if strcmp(app.Leg2AnalyzeDropDown.Value, 'Right')
                app.FPuseDropDown.Value='1';
            else
                app.FPuseDropDown.Value='2';
            end
        end

        % Value changed function: File2scanDropDown
        function File2scanDropDownValueChanged(app, event)

        end

        % Value changed function: AgeyearsEditField
        function AgeyearsEditFieldValueChanged(app, event)

        end

        % Value changed function: NEditField
        function NEditFieldValueChanged(app, event)

        end

        % Value changed function: CutfilesCheckBox
        function CutfilesCheckBoxValueChanged(app, event)

        end

        % Callback function
        function OsimSetupButtonPushed(app, event)

        end

        % Menu selected function: CloseallfiguresMenu
        function CloseallfiguresMenuSelected(app, event)
            close all
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            [icondata,iconcmap] = imread("OlE_Image.png");
            msgbox("Email: patrickm@nih.no", "Success","custom",icondata,iconcmap)
        end

        % Menu selected function: OpenSimMenu
        function OpenSimMenuSelected(app, event)
            try
                txt = org.opensim.modeling.opensimCommon.GetVersion();
                uiwait(msgbox(['OpenSim version: ', char(txt)],"Success","modal"));
            catch
                uiwait(msgbox("OpenSim not detected by MatLab, vist https://se.mathworks.com/help/matlab/ref/msgbox.html","Error","error"));
            end
        end

        % Menu selected function: LoadmatMenu
        function LoadmatMenuSelected(app, event)
            try
                [file,path] = uigetfile('*.mat');
                app.data_path = [path, '/', file];
            catch
            end
        end

        % Menu selected function: OsloMenu
        function OsloMenuSelected(app, event)
            app.lab_setting = 'Oslo';
            app.OLEOpenSimLocomotionEnhancerv05UIFigure.Name = [app.OLEOpenSimLocomotionEnhancerv05UIFigure.Name, ' ', app.lab_setting]
        end

        % Menu selected function: CalgaryMenu
        function CalgaryMenuSelected(app, event)
            app.lab_setting = 'Calgary';
            app.OLEOpenSimLocomotionEnhancerv05UIFigure.Name = [app.OLEOpenSimLocomotionEnhancerv05UIFigure.Name, ' ', app.lab_setting]
        end

        % Value changed function: RefidentifierEditField
        function RefidentifierEditFieldValueChanged(app, event)
            value = app.RefidentifierEditField.Value;

        end

        % Selection changed function: TaskselectionButtonGroup
        function TaskselectionButtonGroupSelectionChanged(app, event)
            selectedButton = app.TaskselectionButtonGroup.SelectedObject;
            indices = find(cellfun(@(x) strcmp(x, app.TaskselectionButtonGroup.SelectedObject.Text), {app.TaskselectionButtonGroup.Buttons(:).Text}));
        end

        % Menu selected function: DashboardMenu
        function DashboardMenuSelected(app, event)
            Dashboard()
        end

        % Value changed function: SetupDropDown
        function SetupDropDownValueChanged(app, event)
            if strcmp(app.SetupDropDown.Value, 'CALGARY')
                app.Button.Text = '180';
                app.Button_2.Text = '90';
                app.RefidentifierEditField.Value = 'neutral';
            elseif strcmp(app.SetupDropDown.Value, 'OSLO')
                app.Button.Text = 'Slow';
                app.Button_2.Text = 'Fast';
                app.RefidentifierEditField.Value = 'Static';
            else
                app.Button.Text = 'Task1';
                app.Button_2.Text = 'Task2';
                app.RefidentifierEditField.Value = 'specify';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OLEOpenSimLocomotionEnhancerv05UIFigure = uifigure('Visible', 'off');
            app.OLEOpenSimLocomotionEnhancerv05UIFigure.Name = 'OLE - OpenSim Locomotion Enhancer v0.5';
            app.OLEOpenSimLocomotionEnhancerv05UIFigure.Icon = fullfile(pathToMLAPP, 'OlE_Image.png');
            app.Menu = uimenu(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Menu.Text = 'Menu';
            app.LoadmatMenu = uimenu(app.Menu);
            app.LoadmatMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadmatMenuSelected, true);
            app.LoadmatMenu.Text = 'Load *.mat';
            app.SetupMenu = uimenu(app.Menu);
            app.SetupMenu.Visible = 'off';
            app.SetupMenu.Enable = 'off';
            app.SetupMenu.Text = 'Setup';
            app.CalgaryMenu = uimenu(app.SetupMenu);
            app.CalgaryMenu.MenuSelectedFcn = createCallbackFcn(app, @CalgaryMenuSelected, true);
            app.CalgaryMenu.Enable = 'off';
            app.CalgaryMenu.Text = 'Calgary';
            app.OsloMenu = uimenu(app.SetupMenu);
            app.OsloMenu.MenuSelectedFcn = createCallbackFcn(app, @OsloMenuSelected, true);
            app.OsloMenu.Enable = 'off';
            app.OsloMenu.Text = 'Oslo';
            app.ToolsMenu = uimenu(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.CloseallfiguresMenu = uimenu(app.ToolsMenu);
            app.CloseallfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallfiguresMenuSelected, true);
            app.CloseallfiguresMenu.Text = 'Close all figures';
            app.HelpMenu = uimenu(app.ToolsMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.OpenSimMenu = uimenu(app.ToolsMenu);
            app.OpenSimMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenSimMenuSelected, true);
            app.OpenSimMenu.Text = 'OpenSim';
            app.DashboardMenu = uimenu(app.ToolsMenu);
            app.DashboardMenu.MenuSelectedFcn = createCallbackFcn(app, @DashboardMenuSelected, true);
            app.DashboardMenu.Text = 'Dashboard';
            app.Menu_2 = uimenu(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Menu_2.Text = ' ';
            app.TextArea_2 = uitextarea(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.TextArea = uitextarea(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.FoldertomonitorEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.FoldertomonitorEditField.Value = 'no folder is monitored';
            app.ButtonGroup = uibuttongroup(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.StartButton = uitogglebutton(app.ButtonGroup);
            app.StartButton.Tooltip = {''};
            app.StartButton.Text = 'Start';
            app.StopButton = uitogglebutton(app.ButtonGroup);
            app.StopButton.Text = 'Stop';
            app.StopButton.Value = true;
            app.MassKgLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.MassKgLabel.Text = 'Mass [Kg]:';
            app.MassKgEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.MassKgEditField.ValueChangedFcn = createCallbackFcn(app, @MassKgEditFieldValueChanged, true);
            app.MassKgEditField.Value = 57;
            app.HeightcmLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.HeightcmLabel.Text = 'Height [cm]:';
            app.HeightcmEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.HeightcmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightcmEditFieldValueChanged, true);
            app.HeightcmEditField.Value = 165;
            app.ForceplateDropDownLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ForceplateDropDownLabel.Text = 'Force plate';
            app.FPuseDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.FPuseDropDown.Items = {'1', '2', '3'};
            app.FPuseDropDown.ValueChangedFcn = createCallbackFcn(app, @FPuseDropDownValueChanged, true);
            app.FPuseDropDown.Value = '2';
            app.AnalyzedLegLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.AnalyzedLegLabel.Text = 'Analyzed Leg';
            app.Leg2AnalyzeDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Leg2AnalyzeDropDown.Items = {'Right', 'Left'};
            app.Leg2AnalyzeDropDown.ValueChangedFcn = createCallbackFcn(app, @Leg2AnalyzeDropDownValueChanged, true);
            app.Leg2AnalyzeDropDown.Value = 'Left';
            app.Label = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Label_2.Text = ' ';
            app.FileformatDropDownLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.FileformatDropDownLabel.Enable = 'off';
            app.FileformatDropDownLabel.Text = 'File format';
            app.File2scanDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.File2scanDropDown.Items = {'.mat', '.c3d', '.trc'};
            app.File2scanDropDown.ValueChangedFcn = createCallbackFcn(app, @File2scanDropDownValueChanged, true);
            app.File2scanDropDown.Enable = 'off';
            app.File2scanDropDown.Value = '.c3d';
            app.SubjectIDLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SubjectIDLabel.Text = 'Subject ID:';
            app.IDEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.IDEditField.Value = 'P00X';
            app.AgeyearsLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.AgeyearsLabel.Text = 'Age [years]:';
            app.AgeyearsEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.AgeyearsEditField.ValueChangedFcn = createCallbackFcn(app, @AgeyearsEditFieldValueChanged, true);
            app.AgeyearsEditField.Value = 32;
            app.NEditFieldLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.NEditFieldLabel.Enable = 'off';
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.NEditField.ValueChangedFcn = createCallbackFcn(app, @NEditFieldValueChanged, true);
            app.NEditField.Enable = 'off';
            app.NEditField.Value = 30;
            app.CutfilesCheckBox = uicheckbox(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.CutfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @CutfilesCheckBoxValueChanged, true);
            app.CutfilesCheckBox.Text = 'Cut';
            app.CutfilesCheckBox.Value = true;
            app.Label_3 = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Label_3.Text = '  ';
            app.PositionLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.PositionLabel.Visible = 'off';
            app.PositionLabel.Text = 'Position:';
            app.PositionEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.PositionEditField.Visible = 'off';
            app.PositionEditField.Value = 'N/A';
            app.DivisionLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.DivisionLabel.Visible = 'off';
            app.DivisionLabel.Text = 'Division:';
            app.DivisionEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.DivisionEditField.Visible = 'off';
            app.DivisionEditField.Value = 'N/A';
            app.InterventionLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.InterventionLabel.Text = 'Intervention:';
            app.InterventionEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.InterventionEditField.Value = 10;
            app.ACLInjuryYearLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ACLInjuryYearLabel.Text = 'ACL Injury Year:';
            app.ACLInjuryYearEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.ACLInjuryYearEditField.Value = '0RLB2022';
            app.DominantLegLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.DominantLegLabel.Visible = 'off';
            app.DominantLegLabel.Text = 'Dominant Leg:';
            app.DominantLegEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.DominantLegEditField.Visible = 'off';
            app.DominantLegEditField.Value = 'RL';
            app.InstructionsgivenCheckBox = uicheckbox(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.InstructionsgivenCheckBox.Text = 'Instructions given';
            app.ThrowingarmLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ThrowingarmLabel.Text = 'Throwing arm/ Kicking Leg:';
            app.ThrowingarmKickingLegEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.ThrowingarmKickingLegEditField.Value = 'RL';
            app.StrengthminEditFieldLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.StrengthminEditFieldLabel.Visible = 'off';
            app.StrengthminEditFieldLabel.Text = 'Strength [min]:';
            app.StrengthminEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.StrengthminEditField.Visible = 'off';
            app.StrengthminEditField.Value = 999;
            app.HandballminEditFieldLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.HandballminEditFieldLabel.Visible = 'off';
            app.HandballminEditFieldLabel.Text = 'Handball [min]:';
            app.HandballminEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.HandballminEditField.Visible = 'off';
            app.HandballminEditField.Value = 998;
            app.ActiveGameminEditFieldLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ActiveGameminEditFieldLabel.Visible = 'off';
            app.ActiveGameminEditFieldLabel.Text = 'Active Game [min]:';
            app.ActiveGameminEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.ActiveGameminEditField.Visible = 'off';
            app.ActiveGameminEditField.Value = 1;
            app.BeforeMeasurmentStartsLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.BeforeMeasurmentStartsLabel.Text = 'Before Measurment Starts';
            app.DuringMeasurmentsLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.DuringMeasurmentsLabel.Text = 'During Measurments';
            app.ButtonGroup_2 = uibuttongroup(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ButtonGroup_2.Tooltip = {'Modify in the Setting.xlsx file in the root directory!'};
            app.ToeLandingButton = uiradiobutton(app.ButtonGroup_2);
            app.ToeLandingButton.Enable = 'off';
            app.ToeLandingButton.Text = 'ToeLanding';
            app.ToeLandingButton.Value = true;
            app.LateralPressButton = uiradiobutton(app.ButtonGroup_2);
            app.LateralPressButton.Enable = 'off';
            app.LateralPressButton.Text = 'Lateral Press';
            app.CoMVelocityButton = uiradiobutton(app.ButtonGroup_2);
            app.CoMVelocityButton.Enable = 'off';
            app.CoMVelocityButton.Text = 'CoM Velocity';
            app.FeedbackTextYes = uiradiobutton(app.ButtonGroup_2);
            app.FeedbackTextYes.Enable = 'off';
            app.FeedbackTextYes.Text = '';
            app.FeedbackTextInput = uieditfield(app.ButtonGroup_2, 'text');
            app.FeedbackTextInput.Enable = 'off';
            app.ToleranceEditFieldLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.ToleranceEditFieldLabel.Visible = 'off';
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'numeric');
            app.ToleranceEditField.Visible = 'off';
            app.ToleranceEditField.Value = 10;
            app.Label_4 = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '%';
            app.FoFCheckBox = uicheckbox(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.FoFCheckBox.Text = 'FoF';
            app.FoFCheckBox.Value = true;
            app.TopfolderButton = uibutton(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'push');
            app.TopfolderButton.ButtonPushedFcn = createCallbackFcn(app, @TopfolderButtonPushed, true);
            app.TopfolderButton.Text = 'Browse';
            app.SportDropDownLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SportDropDownLabel.Text = 'Sport:';
            app.SportDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SportDropDown.Items = {'Soccer', 'Basketball', 'Handball', 'Other'};
            app.SportDropDown.Value = 'Soccer';
            app.SetupDropDownLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SetupDropDownLabel.Text = 'Setup';
            app.SetupDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SetupDropDown.Items = {};
            app.SetupDropDown.ValueChangedFcn = createCallbackFcn(app, @SetupDropDownValueChanged, true);
            app.SetupDropDown.Value = {};
            app.RefidentifierLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.RefidentifierLabel.Text = 'Ref identifier:';
            app.RefidentifierEditField = uieditfield(app.OLEOpenSimLocomotionEnhancerv05UIFigure, 'text');
            app.RefidentifierEditField.ValueChangedFcn = createCallbackFcn(app, @RefidentifierEditFieldValueChanged, true);
            app.RefidentifierEditField.Value = 'neutral';
            app.TaskselectionButtonGroup = uibuttongroup(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.TaskselectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TaskselectionButtonGroupSelectionChanged, true);
            app.TaskselectionButtonGroup.Title = 'Task selection:';
            app.Button = uiradiobutton(app.TaskselectionButtonGroup);
            app.Button.Text = '180';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.TaskselectionButtonGroup);
            app.Button_2.Text = '90';
            app.Button3 = uiradiobutton(app.TaskselectionButtonGroup);
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.SpeedCheckerDropDownLabel = uilabel(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SpeedCheckerDropDownLabel.Text = 'SpeedChecker';
            app.SpeedCheckerDropDown = uidropdown(app.OLEOpenSimLocomotionEnhancerv05UIFigure);
            app.SpeedCheckerDropDown.Items = {'Yes', 'No'};
            app.SpeedCheckerDropDown.Value = 'Yes';
            % Show the figure after all components are created
            app.OLEOpenSimLocomotionEnhancerv05UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OLE_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OLEOpenSimLocomotionEnhancerv05UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OLEOpenSimLocomotionEnhancerv05UIFigure)
        end
    end
end